classdef GUI_GlobalBioIm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GlobalBioImGUIUIFigure         matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        Main                           matlab.ui.container.Tab
        LabelGlobalBioIm               matlab.ui.control.Label
        ButtonSaveMainPanel            matlab.ui.control.Button
        ButtonSelectPipeline           matlab.ui.control.Button
        DropDownPipeline               matlab.ui.control.DropDown
        PipelineEditFieldLabel         matlab.ui.control.Label
        EditFieldPipeline              matlab.ui.control.EditField
        DescriptionTextAreaLabel       matlab.ui.control.Label
        TextAreaDescriptionPipeline    matlab.ui.control.TextArea
        LabelCopyright                 matlab.ui.control.Label
        ForwardModel                   matlab.ui.container.Tab
        TextAreaForwardModel           matlab.ui.control.TextArea
        LabelTextArea                  matlab.ui.control.Label
        ButtonSelectLinOp              matlab.ui.control.Button
        ButtonSeeDocLinOp              matlab.ui.control.Button
        OperatorLabel                  matlab.ui.control.Label
        DropDownLinOp                  matlab.ui.control.DropDown
        PanelHierarchyLinOp            matlab.ui.container.Panel
        NotationLinOp1                 matlab.ui.control.Label
        NameLinOp1                     matlab.ui.control.Label
        ButtonSetParamsLinOp1          matlab.ui.control.Button
        ButtonUpLinOp1                 matlab.ui.control.Button
        ButtonDownLinOp1               matlab.ui.control.Button
        ButtonDeleteLinOp1             matlab.ui.control.Button
        NotationLinOp2                 matlab.ui.control.Label
        NameLinOp2                     matlab.ui.control.Label
        ButtonSetParamsLinOp2          matlab.ui.control.Button
        ButtonUpLinOp2                 matlab.ui.control.Button
        ButtonDownLinOp2               matlab.ui.control.Button
        ButtonDeleteLinOp2             matlab.ui.control.Button
        NotationLinOp3                 matlab.ui.control.Label
        NameLinOp3                     matlab.ui.control.Label
        ButtonSetParamsLinOp3          matlab.ui.control.Button
        ButtonUpLinOp3                 matlab.ui.control.Button
        ButtonDownLinOp3               matlab.ui.control.Button
        ButtonDeleteLinOp3             matlab.ui.control.Button
        NotationLinOp4                 matlab.ui.control.Label
        NameLinOp4                     matlab.ui.control.Label
        ButtonSetParamsLinOp4          matlab.ui.control.Button
        ButtonUpLinOp4                 matlab.ui.control.Button
        ButtonDownLinOp4               matlab.ui.control.Button
        ButtonDeleteLinOp4             matlab.ui.control.Button
        NotationLinOp5                 matlab.ui.control.Label
        NameLinOp5                     matlab.ui.control.Label
        ButtonSetParamsLinOp5          matlab.ui.control.Button
        ButtonUpLinOp5                 matlab.ui.control.Button
        ButtonDownLinOp5               matlab.ui.control.Button
        ButtonDeleteLinOp5             matlab.ui.control.Button
        NotationLinOp6                 matlab.ui.control.Label
        NameLinOp6                     matlab.ui.control.Label
        ButtonSetParamsLinOp6          matlab.ui.control.Button
        ButtonUpLinOp6                 matlab.ui.control.Button
        ButtonDownLinOp6               matlab.ui.control.Button
        ButtonDeleteLinOp6             matlab.ui.control.Button
        ButtonInfoLinOp                matlab.ui.control.Button
        ButtonCheckPipelineFwdPanel    matlab.ui.control.Button
        ButtonSaveFwdPanel             matlab.ui.control.Button
        CostFunction                   matlab.ui.container.Tab
        TextAreaCost                   matlab.ui.control.TextArea
        CostFunctionLabel              matlab.ui.control.Label
        ButtonSelectDF                 matlab.ui.control.Button
        ButtonSeeDocDF                 matlab.ui.control.Button
        DatafidelityLabel              matlab.ui.control.Label
        DropDownDF                     matlab.ui.control.DropDown
        FMPanel_CostFunction           matlab.ui.container.Panel
        NotationRegul1                 matlab.ui.control.Label
        NameRegul1                     matlab.ui.control.Label
        ButtonSetParamsRegul1          matlab.ui.control.Button
        ButtonDeleteRegul1             matlab.ui.control.Button
        NotationRegul2                 matlab.ui.control.Label
        NameRegul2                     matlab.ui.control.Label
        ButtonSetParamsRegul2          matlab.ui.control.Button
        ButtonDeleteRegul2             matlab.ui.control.Button
        NotationRegul3                 matlab.ui.control.Label
        NameRegul3                     matlab.ui.control.Label
        ButtonSetParamsRegul3          matlab.ui.control.Button
        ButtonDeleteRegul3             matlab.ui.control.Button
        ButtonSelectRegul              matlab.ui.control.Button
        ButtonSeeDocRegul              matlab.ui.control.Button
        RegularizerLabel               matlab.ui.control.Label
        DropDownRegul                  matlab.ui.control.DropDown
        CheckBoxPositivity             matlab.ui.control.CheckBox
        FMPanel_CostFunction_2         matlab.ui.container.Panel
        NotationDF1                    matlab.ui.control.Label
        NameDF1                        matlab.ui.control.Label
        ButtonSetParamsDF1             matlab.ui.control.Button
        ButtonDeleteDF1                matlab.ui.control.Button
        ButtonInfoDF                   matlab.ui.control.Button
        ButtonInfoRegul                matlab.ui.control.Button
        ButtonSaveCostPanel            matlab.ui.control.Button
        ButtonCheckPipelineCostPanel   matlab.ui.control.Button
        Optimization                   matlab.ui.container.Tab
        FMPanel_Optimization           matlab.ui.container.Panel
        NameOpti1                      matlab.ui.control.Label
        ButtonSetParamsOpti1           matlab.ui.control.Button
        ButtonDeleteOpti1              matlab.ui.control.Button
        ButtonSelectOpti               matlab.ui.control.Button
        ButtonSeeDocOpti               matlab.ui.control.Button
        AlgorithmLabel                 matlab.ui.control.Label
        DropDownOpti                   matlab.ui.control.DropDown
        ButtonInfoOpti                 matlab.ui.control.Button
        ButtonRUN                      matlab.ui.control.Button
        ButtonGenerateScript           matlab.ui.control.Button
        ButtonParametersRecap          matlab.ui.control.Button
        CheckBoxCostFunctionDisplay    matlab.ui.control.CheckBox
        ButtonSaveOptiPanel            matlab.ui.control.Button
        LineOptiPanel                  matlab.ui.control.Label
        MetaOptionsLabel               matlab.ui.control.Label
        CheckBoxCostFunctionDisplay_2  matlab.ui.control.CheckBox
        ButtonInfoOpti_2               matlab.ui.control.Button
        ButtonInfoOpti_3               matlab.ui.control.Button
        ButtonCheckPipelineAlgoPanel   matlab.ui.control.Button
    end

    % ======================================
    % Programatically added private Properties
    % ======================================
    properties (Access = private)
        
        % Global properties
        doc_weblink = 'biomedical-imaging-group.github.io/GlobalBioIm/'; % Link to online documentation
        
        % Properties for ForwardModel panel
        maxLinOp         = 6;      % maximum number of concatenable linOps
        
        % Properties for CostFunction panel
        maxDF            = 1;      % maximum number of data fidelity terms
        maxRegul         = 3;      % maximum number of regularization terms
        DFText                     % text to display for selected DF
        RegulText                  % text to display for selected Reguls
        PosText                    % text to display for selected Positivity
        dfListGUI        = {'Quadratic (L2)', 'Kullback-Leibler (KL)'}; % proposed data-fidelity terms (in GUI)
        dfListCode       = {'L2', 'KullLeib'};                          % corresponding data-fidelity terms (in code)
        
        % Properties for Optimization panel
        maxOpti          = 1;      % maximum number of algorithm (to be coherent)
        
        % Information for display
        incr_height   = 50;
        def_height    = 20;
        
    end
    
    % ======================================
    % Programatically added public Properties
    % ======================================
    properties (Access = public)
        
        LinOpCell           % Cell array containing the LinOps (ForwardModel panel)
                            % Each element of this cell contains a structure with fields
                            %   - name     (name of the LinOp)
                            %   - notation (letter/symbol used to represent the LinOp)
                            %   - call     (command line used to instanciate the LinOp)
                            %   - parameters (cell with fields: name/type/val/info)
        
        DFCell              % Cell array containing the data-fidelity term (CostFunction panel)
                            % Each element of this cell contains a structure with fields
                            %   - name     (name of the DF)
                            %   - notation (letter/symbol used to represent the DF)
                            %   - call     (command line used to instanciate the DF)
                            %   - parameters (cell with fields: name/type/val/info)
        
        RegulCell           % Cell array containing the regularizations (CostFunction panel)
                            % Each element of this cell contains a structure with fields
                            %   - name     (name of the Regul)
                            %   - notation (letter/symbol used to represent the Regul)
                            %   - call     (command line used to instanciate the Regul)
                            %   - parameters (cell with fields: name/type/val/info)
                            
        OptiCell            % Cell array containing the minimzation algorithms (Optimization panel)
                            % Each element of this cell contains a structure with fields
                            %   - name     (name of the Opti)
                            %   - notation (letter/symbol used to represent the Opti)
                            %   - call     (command line used to instanciate the Opti)
                            %   - parameters (cell with fields: name/type/val/info)
                            
        isPositivity        % Boolean true if positivity is activated                 
        
        SetParamsFigs       % Cell array used to manage the set parameters figures
        nbSetParamsFigs     % Number of opened Set Parameters Figures

        logwd=[]            % Log window
        
        script=struct('header',{{'%----------------------------------------------'}}, ...
            'params',{{'%% Load/Read Parameters'}}, ...
            'linop',{{'%% Instanciate the Forward Model'}}, ...
            'cost',{{'%% Instanciate the Cost function'}}, ...
            'algo',{{'%% Instanciate and Run the Optimization method'}});
        
        pipeline            % structure with fields
                            %    -name  (name of the pipeline)
                            %    -dscpt (description of the pipeline)
    end
    
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
    % ======================================
    %           COMMON METHODS
    % ======================================
    methods (Access = private)    
        % -- Init properties
        function InitProperties(app)
            app.SetParamsFigs=cell(1,app.maxLinOp+app.maxDF+app.maxRegul+app.maxOpti);
            app.nbSetParamsFigs=struct('LinOp',0,'DF',0,'Regul',0, 'Opti', 0);
            app.LinOpCell={};app.DFCell={};app.RegulCell={};app.OptiCell={}; 
            ResetScript(app);
            app.isPositivity=false;
        end
    
        % -- Reset Script
        function ResetScript(app)
            app.script=struct('header',{{'%----------------------------------------------'}}, ...
                'params',{{'%% Load/Read Parameters'}}, ...
                'linop',{{'%% Instanciate the Forward Model'}}, ...
                'cost',{{'%% Instanciate the Cost function'}}, ...
                'algo',{{'%% Instanciate and Run the Optimization method'}});
        end
        
        % -- Open Log window
        function OpenLog(app,msg,col)
            app.logwd.fig = uifigure;
            app.logwd.fig.Position =app.GlobalBioImGUIUIFigure.Position  +  [0 app.GlobalBioImGUIUIFigure.Position(4)/3 0 -app.GlobalBioImGUIUIFigure.Position(4)*2/3];
            app.logwd.fig.Name  = 'Log';
            app.logwd.TextArea = uitextarea(app.logwd.fig);
            app.logwd.TextArea.Value = msg;
            if isequal(col,'green')
                app.logwd.TextArea.FontColor=[0 0.5 0];
            else
                app.logwd.TextArea.FontColor=col;
            end
            app.logwd.TextArea.Position = [10 10 app.logwd.fig.Position(3)-20 app.logwd.fig.Position(4)-20];
            app.logwd.TextArea.Editable = 'on';
        end
        
        % -- Value Changed function: ValueChanged_SetParamsFigEdit
        function ValueChanged_SetParamsFigEdit(app,event,ii)
            tag=event.Source.Tag(1:end-1);          % Get if LinOp / DF / Regul
            idx=str2double(event.Source.Tag(end));  % Get the index of the object to deal with
            switch tag
                case 'LinOp'
                    idx2=idx;
                case 'DF'
                    idx2=app.maxLinOp+idx;
                case 'Regul'
                    idx2=app.maxLinOp+app.maxDF+idx;
                case 'Opti'
                    idx2=app.maxLinOp+app.maxDF+app.maxRegul+idx;
            end
            eval(['app.',tag,'Cell{idx}.parameters{ii}.val=app.SetParamsFigs{idx2}.param{ii}.EditField.Value;']);
        end
    
        % -- Value changed function: ValueChanged_SetParamsFigDropDown
        function ValueChanged_SetParamsFigDropDown(app, event,ii)
            tag=event.Source.Tag(1:end-1);          % Get if LinOp / DF / Regul
            idx=str2double(event.Source.Tag(end));  % Get the index of the object to deal with
            switch tag
                case 'LinOp'
                    idx2=idx;
                case 'DF'
                    idx2=app.maxLinOp+idx;
                case 'Regul'
                    idx2=app.maxLinOp+app.maxDF+idx;
                case 'Opti'
                    idx2=app.maxLinOp+app.maxDF+app.maxRegul+idx;
            end
            eval(['app.',tag,'Cell{idx}.parameters{ii}.val=app.SetParamsFigs{idx2}.param{ii}.dropDown.Value;']);
        end
        
        % -- Value changed function: ValueChanged_SetParamsFigBoolean
        function ValueChanged_SetParamsFigBoolean(app, event,ii)
            tag=event.Source.Tag(1:end-1);          % Get if LinOp / DF / Regul
            idx=str2double(event.Source.Tag(end));  % Get the index of the object to deal with
            switch tag
                case 'LinOp'
                    idx2=idx;
                case 'DF'
                    idx2=app.maxLinOp+idx;
                case 'Regul'
                    idx2=app.maxLinOp+app.maxDF+idx;
                case 'Opti'
                    idx2=app.maxLinOp+app.maxDF+app.maxRegul+idx;
            end
            eval(['app.',tag,'Cell{idx}.parameters{ii}.val=app.SetParamsFigs{idx2}.param{ii}.boolean.Value;']);
        end

        % -- Button Pushed function: ButtonPushed_SetParamsFigBrowse
        function ButtonPushed_SetParamsFigBrowse(app,event,ii)
            tag=event.Source.Tag(1:end-1);          % Get if LinOp / DF / Regul
            idx=str2double(event.Source.Tag(end));  % Get the index of the object to deal with
            switch tag
                case 'LinOp'
                    idx2=idx;
                case 'DF'
                    idx2=app.maxLinOp+idx;
                case 'Regul'
                    idx2=app.maxLinOp+app.maxDF+idx;
                case 'Opti'
                    idx2=app.maxLinOp+app.maxDF+app.maxRegul+idx;
            end
            [file,path]=uigetfile();
            if file~=0
                eval(['app.',tag,'Cell{idx}.parameters{ii}.val=[path,file];']);
                app.SetParamsFigs{idx2}.param{ii}.TextArea.Value=[path,file];
            end
        end
    
        % -- Button pushed function: ButtonPushed_ApplyChanges
        function ButtonPushed_ApplyChanges(app,event)
            tag=event.Source.Tag(1:end-1);          % Get if LinOp / DF / Regul
            idx=str2double(event.Source.Tag(end));  % Get the index of the object to deal with
            switch tag
                case 'LinOp'
                    pp=app.LinOpCell{idx}.parameters;
                    nButtom=length(app.LinOpCell);
                    idx2=idx;
                case 'DF'
                    pp=app.DFCell{idx}.parameters;
                    nButtom=length(app.DFCell);
                    idx2=app.maxLinOp+idx;
                case 'Regul'
                    pp=app.RegulCell{idx}.parameters;
                    nButtom=length(app.RegulCell);
                    idx2=app.maxLinOp+app.maxDF+idx;
                case 'Opti'
                    pp=app.OptiCell{idx}.parameters;
                    nButtom=length(app.OptiCell);
                    idx2=app.maxLinOp+app.maxDF+app.maxRegul+idx;
            end
            % Check if syntax error
            if (~isempty(app.logwd) && ishandle(app.logwd.fig)), close(app.logwd.fig); end
            err=0;msg={};
            for ii=1:length(pp)
                typ=pp{ii}.type;
                switch  typ
                    case 'vecInt'
                        tmp=arrayfun(@(x) [num2str(x),' '],str2num(pp{ii}.val),'UniformOutput',false);
                        tmp=horzcat(tmp{:}); tmp=tmp(1:end-1);
                        if ~isempty(pp{ii}.val) &&  (~strcmp(tmp,pp{ii}.val) || all(mod(str2num(pp{ii}.val),1)~=0))
                            err=1;
                            msg{length(msg)+1}=['Wrong entry for parameter ',pp{ii}.name];
                            msg{length(msg)+1}='    --> A sequence of integers separated by a space is required (e.g. 512 512)';
                            msg{length(msg)+1}=' ';
                            app.SetParamsFigs{idx2}.param{ii}.label.FontColor='red';
                        else
                            app.SetParamsFigs{idx2}.param{ii}.label.FontColor='black';
                        end
                end
            end
            % If no errors make the figure visible off
            if err==0
                app.SetParamsFigs{idx2}.fig.Visible='off';
                eval(['app.nbSetParamsFigs.',tag,'=app.nbSetParamsFigs.',tag,'-1;']); % Increase the counter of opened Set Params Figs
                eval(['bb=(app.nbSetParamsFigs.',tag,'==0);']);
                if bb
                    for ii=1:nButtom
                        if strcmp(tag,'LinOp')
                            app = setfield(app,['ButtonUp',tag,num2str(ii)],'Enable','on');
                            app = setfield(app,['ButtonDown',tag,num2str(ii)],'Enable','on');
                        end
                        app = setfield(app,['ButtonDelete',tag,num2str(ii)],'Enable','on');
                    end
                    eval(['enab=(length(app.',tag,'Cell)<app.max',tag,');']);
                    if enab
                        app = setfield(app,['ButtonSelect',tag],'Enable','on');
                    end
                end
                app = setfield(app,['ButtonSetParams',event.Source.Tag],'Enable','on');
            else
                OpenLog(app,msg,'red');
            end
        end
        
        % -- Update parameters section in script
        function errMsg=UpdateScript_ParametersSec(app,obj,prefix)            
            nlinesParams=length(app.script.params);
            errMsg={};
            for ii=1:length(obj.parameters)
                if isempty(obj.parameters{ii}.val)
                    app.script.params{nlinesParams+ii}=[prefix,'_',obj.parameters{ii}.name,' = [];'];
                else
                    typ = obj.parameters{ii}.type;
                    switch  typ
                        case 'vecInt'
                            app.script.params{nlinesParams+ii}=[prefix,'_',obj.parameters{ii}.name,' = [',obj.parameters{ii}.val,'];'];
                        case 'file'
                            ext = regexp(obj.parameters{ii}.val,'\.','split');
                            ext=ext{end};
                            switch ext
                                case 'tif'
                                case {'png','jpg'}
                                    app.script.params{nlinesParams+ii}=[prefix,'_',obj.parameters{ii}.name,' = double(imread("',obj.parameters{ii}.val,'"));'];
                                case 'mat'
                                otherwise
                                    errMsg{length(errMsg)+1}=[obj.parameters{ii}.name,' of ',obj.name,' : Unknown file extension .',ext];
                                    errMsg{length(errMsg)+1}='    --> Accepted formats: .tif, .png, .jpg, .mat';
                                    errMsg{length(errMsg)+1}=' ';
                            end
                        case 'double'
                            app.script.params{nlinesParams+ii}=[prefix,'_',obj.parameters{ii}.name,' = ',num2str(obj.parameters{ii}.val),';'];
                        case 'dropDown'
                            app.script.params{nlinesParams+ii}=[prefix,'_',obj.parameters{ii}.name,' = ''',obj.parameters{ii}.val,''';'];
                    end
                end
            end
        end
    
        % -- Update Call with Prefix
        function strUp=UpdateScript_SetPrefixToCalls(app,str,obj,prefix)
            strUp=str;
            for ii=1:length(obj.parameters)
                strUp=strrep(strUp,['(',obj.parameters{ii}.name],['(',prefix,'_',obj.parameters{ii}.name]);
                strUp=strrep(strUp,[',',obj.parameters{ii}.name,','],[',',prefix,'_',obj.parameters{ii}.name,',']);
                strUp=strrep(strUp,[obj.parameters{ii}.name,')'],[prefix,'_',obj.parameters{ii}.name,')']);
            end
        end
    end
    
    % ======================================
    %          MAIN PANEL METHODS
    % ======================================
    methods (Access = private)
        % -- Get list of Pipelines in dropdown menu
        function ListOfPipelines = GetListOfAvailablePipelines(app)
            dirList=struct2cell(dir('pipelines/*mat'));
            dirList=dirList(1,:);
            ListOfPipelines=strrep(dirList,'.mat','');
        end
    
        % -- Initialize Main Panel
        function InitializeMainPanel(app)
            ListOfPipelines = GetListOfAvailablePipelines(app);
            if isempty(ListOfPipelines)
                ListOfPipelines = {'New','Browse'};
            else
                ListOfPipelines =  ['New','Browse',{ListOfPipelines{:}}];
            end
            app.DropDownPipeline.Items = ListOfPipelines;         
        end
    end
 
    % ======================================
    %      FORWARD MODEL PANEL METHODS
    % ======================================
    methods (Access = private)
        % -- Get list of linOps in dropdown menu
        function ListOfLinOpsStr = GetListOfAvailableLinOps(app)
            dirList1 = struct2cell(dir('../LinOp/LinOp*.m'));
            dirList2 = struct2cell(dir('../LinOp/SelectorLinOps/LinOp*.m'));
            dirList = [dirList1 dirList2];
            idx=[];
            for ii=1:length(dirList)
                if ~isempty(regexp(fileread(dirList{1,ii}),'GUI-Header','once'))
                    idx=[idx,ii];
                end
            end
            dirList=dirList(1,idx);
            ListOfLinOpsStr = strrep(strrep(dirList,'.m',''),'LinOp','');
        end
       
        % -- Initialize Forward Model Panel
        function InitializeForwardModelPanel(app)
            % Get the list of LinOps
            ListOfLinOpsStr = GetListOfAvailableLinOps(app);
            
            % Display dropdown GUI list
            app.DropDownLinOp.Items = ListOfLinOpsStr;
            
            % Hide hierarchy of linOps
            HideLinOp(app);
            
            % Set Tags
            app.ButtonSelectLinOp.Tag='LinOp';
            app.ButtonSeeDocLinOp.Tag = 'LinOp'; 
            app.ButtonDeleteLinOp1.Tag='LinOp1';app.ButtonDeleteLinOp2.Tag='LinOp2';app.ButtonDeleteLinOp3.Tag='LinOp3';
            app.ButtonDeleteLinOp4.Tag='LinOp4';app.ButtonDeleteLinOp5.Tag='LinOp5';app.ButtonDeleteLinOp6.Tag='LinOp6';
            app.ButtonUpLinOp1.Tag='LinOp1';app.ButtonUpLinOp2.Tag='LinOp2';app.ButtonUpLinOp3.Tag='LinOp3';
            app.ButtonUpLinOp4.Tag='LinOp4';app.ButtonUpLinOp5.Tag='LinOp5';app.ButtonUpLinOp6.Tag='LinOp6';
            app.ButtonDownLinOp1.Tag='LinOp1';app.ButtonDownLinOp2.Tag='LinOp2';app.ButtonDownLinOp3.Tag='LinOp3';
            app.ButtonDownLinOp4.Tag='LinOp4';app.ButtonDownLinOp5.Tag='LinOp5';app.ButtonDownLinOp6.Tag='LinOp6';
            app.ButtonSetParamsLinOp1.Tag='LinOp1';app.ButtonSetParamsLinOp2.Tag='LinOp2';app.ButtonSetParamsLinOp3.Tag='LinOp3';
            app.ButtonSetParamsLinOp4.Tag='LinOp4';app.ButtonSetParamsLinOp5.Tag='LinOp5';app.ButtonSetParamsLinOp6.Tag='LinOp6';
        end
    
        % -- Hide hierarchy of linOps
        function HideLinOp(app)
            app.NotationLinOp1.Visible='off';app.NotationLinOp2.Visible='off';app.NotationLinOp3.Visible='off';
            app.NotationLinOp4.Visible='off';app.NotationLinOp5.Visible='off';app.NotationLinOp6.Visible='off';
            app.NameLinOp1.Visible='off';app.NameLinOp2.Visible='off';app.NameLinOp3.Visible='off';
            app.NameLinOp4.Visible='off';app.NameLinOp5.Visible='off';app.NameLinOp6.Visible='off';
            app.ButtonSetParamsLinOp1.Visible='off';app.ButtonSetParamsLinOp2.Visible='off';app.ButtonSetParamsLinOp3.Visible='off';
            app.ButtonSetParamsLinOp4.Visible='off';app.ButtonSetParamsLinOp5.Visible='off';app.ButtonSetParamsLinOp6.Visible='off';
            app.ButtonUpLinOp1.Visible='off';app.ButtonUpLinOp2.Visible='off';app.ButtonUpLinOp3.Visible='off';
            app.ButtonUpLinOp4.Visible='off';app.ButtonUpLinOp5.Visible='off';app.ButtonUpLinOp6.Visible='off';
            app.ButtonDownLinOp1.Visible='off';app.ButtonDownLinOp2.Visible='off';app.ButtonDownLinOp3.Visible='off';
            app.ButtonDownLinOp4.Visible='off';app.ButtonDownLinOp5.Visible='off';app.ButtonDownLinOp6.Visible='off';
            app.ButtonDeleteLinOp1.Visible='off';app.ButtonDeleteLinOp2.Visible='off';app.ButtonDeleteLinOp3.Visible='off';
            app.ButtonDeleteLinOp4.Visible='off';app.ButtonDeleteLinOp5.Visible='off';app.ButtonDeleteLinOp6.Visible='off';
        end
        
        % -- Show hierarchy of linOps
        function ShowLinOp(app)
            % Number of LinOps
            nLinOps = length(app.LinOpCell);
            
            % Loop over all the selected LinOps
            LinOpText = 'H = ';
            for idx=1:nLinOps
                % Notation Field
                app=setfield(app,['NotationLinOp',num2str(idx)],'Visible','on');
                app=setfield(app,['NotationLinOp',num2str(idx)],'Text',[app.LinOpCell{idx}.notation, ' : ']);
                % Name Field
                app=setfield(app,['NameLinOp',num2str(idx)],'Visible','on');
                app=setfield(app,['NameLinOp',num2str(idx)],'Text',['LinOp' ,app.LinOpCell{idx}.name]);
                % Set parameter button
                app=setfield(app,['ButtonSetParamsLinOp',num2str(idx)],'Visible','on');
                % Up button
                if idx > 1
                    app=setfield(app,['ButtonUpLinOp',num2str(idx)],'Visible','on');
                end
                % Down button
                if idx < nLinOps
                    app=setfield(app,['ButtonDownLinOp',num2str(idx)],'Visible','on');
                end
                % Delete button
                app=setfield(app,['ButtonDeleteLinOp',num2str(idx)],'Visible','on');                                
                % Update text for concatenation of linOps
                LinOpText = [LinOpText app.LinOpCell{idx}.notation];
            end
            
            % Update text for concatenation of linOps
            app.TextAreaForwardModel.Value = LinOpText;            
        end
    end
    
    % ======================================
    %           COST FUNCTION PANEL
    % ======================================
    methods (Access = private) 
        % -- Get list of reguls in dropdown menu
        function ListOfRegulsStr = GetListOfAvailableReguls(app)
            dirList = struct2cell(dir('Regul/Regul*.m'));
            ListOfRegulsStr = strrep(strrep(dirList(1,:),'.m',''),'Regul','');
        end
        
        % -- Initialize Cost Function Panel
        function InitializeCostFunctionPanel(app)
            % Get the list of Reguls
            ListOfRegulsStr = GetListOfAvailableReguls(app);
            
            % Display both dropdown GUI lists
            app.DropDownDF.Items = app.dfListGUI;
            app.DropDownRegul.Items = ListOfRegulsStr;
            
            % Hide DF and Regul
            HideDF(app);
            HideRegul(app);
            
            % Set Tags
            app.ButtonSelectDF.Tag = 'DF';
            app.ButtonSelectRegul.Tag = 'Regul';
            app.ButtonSeeDocDF.Tag = 'DF';
            app.ButtonSeeDocRegul.Tag = 'Regul';
            app.ButtonDeleteDF1.Tag='DF1';
            app.ButtonDeleteRegul1.Tag='Regul1';app.ButtonDeleteRegul2.Tag='Regul2';app.ButtonDeleteRegul3.Tag='Regul3';
            app.ButtonSetParamsDF1.Tag='DF1';
            app.ButtonSetParamsRegul1.Tag='Regul1';app.ButtonSetParamsRegul2.Tag='Regul2';app.ButtonSetParamsRegul3.Tag='Regul3';
        end
           
        % -- Hide DF
        function HideDF(app)
            app.NotationDF1.Visible='off';
            app.NameDF1.Visible='off';
            app.ButtonDeleteDF1.Visible='off';
            app.ButtonSetParamsDF1.Visible='off';
        end
        
        % -- Hide Regul
        function HideRegul(app)
            app.NotationRegul1.Visible='off';app.NotationRegul2.Visible='off';app.NotationRegul3.Visible='off';
            app.NameRegul1.Visible='off';app.NameRegul2.Visible='off';app.NameRegul3.Visible='off';
            app.ButtonDeleteRegul1.Visible='off';app.ButtonDeleteRegul2.Visible='off';app.ButtonDeleteRegul3.Visible='off';
            app.ButtonSetParamsRegul1.Visible='off';app.ButtonSetParamsRegul2.Visible='off';app.ButtonSetParamsRegul3.Visible='off';
        end
                
        % -- Show DF
        function ShowDF(app)
            % Number of data fidelity and reguls
            nDF = length(app.DFCell);
            
            % Initialize CostText
            app.DFText = '' ;
            
            % Loop over all the selected data fidelity            
            for idx=1:nDF
                % Notation Field
                app=setfield(app,['NotationDF',num2str(idx)],'Visible','on');
                app=setfield(app,['NotationDF',num2str(idx)],'Text',[app.DFCell{idx}.notation, ' : ']);
                % Name Field
                app=setfield(app,['NameDF',num2str(idx)],'Visible','on');
                app=setfield(app,['NameDF',num2str(idx)],'Text',['Cost' ,app.DFCell{idx}.name]);
                % Set parameter button
                app=setfield(app,['ButtonSetParamsDF',num2str(idx)],'Visible','on');
                % Delete button
                app=setfield(app,['ButtonDeleteDF',num2str(idx)],'Visible','on');                                
                % Update text for concatenation of linOps
                app.DFText = [app.DFText app.DFCell{idx}.notation,'(Hx,y)'];
            end
            
            % Update text for cost
            app.TextAreaCost.Value = ['C(x) = ',app.DFText,app.RegulText, app.PosText];                  
        end
    
        % -- Show Regul
        function ShowRegul(app)
            % Number of data fidelity and reguls
            nReg = length(app.RegulCell);
            
            % Initialize CostText
            app.RegulText = '' ;
            
            % Loop over all the selected data fidelity
            for idx=1:nReg
                % Notation Field
                app=setfield(app,['NotationRegul',num2str(idx)],'Visible','on');
                app=setfield(app,['NotationRegul',num2str(idx)],'Text',[app.RegulCell{idx}.notation, ' : ']);
                % Name Field
                app=setfield(app,['NameRegul',num2str(idx)],'Visible','on');
                app=setfield(app,['NameRegul',num2str(idx)],'Text',[app.RegulCell{idx}.name]);
                % Set parameter button
                app=setfield(app,['ButtonSetParamsRegul',num2str(idx)],'Visible','on');
                % Delete button
                app=setfield(app,['ButtonDeleteRegul',num2str(idx)],'Visible','on');
                % Update text for concatenation of linOps
                app.RegulText = [app.RegulText ' + ' char(955) char(8320+idx) app.RegulCell{idx}.notation '(x)'];
            end           
            
            % Update text for cost
            app.TextAreaCost.Value = ['C(x) = ',app.DFText,app.RegulText, app.PosText];
        end    
    end
    
    % ======================================
    %           OPTIMIZATION PANEL
    % ======================================
    methods (Access = private)       
        % -- Set list of Opti in dropdown menu
        function ListOfOptiStr = GetListOfAvailableOpti(app)
            dirList = struct2cell(dir('../Opti/Opti*.m'));
            idx=[];
            for ii=1:length(dirList)
                if ~isempty(regexp(fileread(dirList{1,ii}),'GUI-Header','once'))
                    idx=[idx,ii];
                end
            end
            dirList=dirList(1,idx);
            ListOfOptiStr = strrep(strrep(dirList,'.m',''),'Opti','');
        end
        
        % -- Initialize Optimization Panel
        function InitializeOptimizationPanel(app)
            
            % Get the list of Optis
            ListOfOptiStr = GetListOfAvailableOpti(app);
            
            % Display dropdown GUI list
            app.DropDownOpti.Items = ListOfOptiStr;
            
            % Hide hierarchy of Optis
            HideOpti(app);
            
            % Set Tags
            app.ButtonSelectOpti.Tag='Opti';
            app.ButtonSeeDocOpti.Tag = 'Opti'; 
            app.ButtonDeleteOpti1.Tag='Opti1';
            app.ButtonSetParamsOpti1.Tag='Opti1';
        end
        
        % -- Hide Opti
        function HideOpti(app)
            app.NameOpti1.Visible='off';
            app.ButtonDeleteOpti1.Visible='off';
            app.ButtonSetParamsOpti1.Visible='off';
        end
        
        % -- Show Opti
        function ShowOpti(app)
            if length(app.OptiCell)==1
                % Name Field
                app=setfield(app,'NameOpti1','Visible','on');
                app=setfield(app,'NameOpti1','Text',['Opti' ,app.OptiCell{1}.name]);
                % Set parameter button
                app=setfield(app,'ButtonSetParamsOpti1','Visible','on');
                % Delete button
                app=setfield(app,'ButtonDeleteOpti1','Visible','on');
            end
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % GENERAL ---------------
            InitProperties(app);
            
            % MAIN PANEL --------------
            InitializeMainPanel(app);
            
            % FORWARD MODEL PANEL ------------------
            InitializeForwardModelPanel(app);
            
            % COST FUNCTION PANEL ------------------
            InitializeCostFunctionPanel(app);      
            
            % COST FUNCTION PANEL ------------------
            InitializeOptimizationPanel(app);   
            
        end

        % Button pushed function: ButtonSeeDocDF, ButtonSeeDocLinOp, 
        % ButtonSeeDocOpti, ButtonSeeDocRegul
        function ButtonPushed_SeeDoc(app, event)
            tag=event.Source.Tag;
            switch tag
                case 'LinOp'
                    ll = ['linop.html#linop' lower(app.DropDownLinOp.Value)];
                case 'DF'
                    idx = strcmp(app.DropDownDF.Items,app.DropDownDF.Value);
                    ll = ['cost.html#cost' lower(app.dfListCode{idx})];
                case 'Regul'
                    % TODO: SEE WHAT TO DO
                    % TODO: ALSO THE CASE IN THE FIGURE SET PARAMETERS
                case 'Opti'
                    ll = ['opti.html#opti' lower(app.DropDownOpti.Value)];
            end
            web([app.doc_weblink ll], '-notoolbar');
        end

        % Button pushed function: ButtonCheckPipelineFwdPanel
        function ButtonPushed_CheckPipeline(app, event)
            % Reset Script
            ResetScript(app);
                                    
            %==============================
            % Check Forward Model
            %==============================
            % Initialize error message
            errMsgLinOp=[{'## Check Forward Model : '},{' '}];
            
            % Check parameters section
            for idx=1:length(app.LinOpCell)       % Loop over LinOps
                Op=['H',num2str(idx)];            
                msg=UpdateScript_ParametersSec(app,app.LinOpCell{idx},Op);
                errMsgLinOp=[errMsgLinOp,msg];
            end
            try
                eval(sprintf('%s\n',app.script.params{:}));
            catch ME
                errMsgLinOp=[errMsgLinOp,['In LinOps parameters setting: ',ME.message],' '];
            end
                       
            % Check LinOp Instanciation
%             for idx=1:length(app.LinOpCell)               
%                 Op=['H',num2str(idx)];                                                    
%                 app.script.linop{length(app.script.linop)+1} = UpdateScript_SetPrefixToCalls(app,[Op,' = ',app.LinOpCell{idx}.call,';'],app.LinOpCell{idx},Op);  % Instanciation line    
%                 try
%                     eval(sprintf('%s\n',app.script.linop{end}));
%                 catch ME
%                     errMsgLinOp=[errMsgLinOp,['In ',app.LinOpCell{idx}.name,': ',ME.message],' '];
%                 end
%             end
            
            % Test if failed
            if length(errMsgLinOp) >2
                errMsgLinOp{1} = [errMsgLinOp{1},' Failed !'];
                colLinOp='red';
            else
                errMsgLinOp{1} = [errMsgLinOp{1},' Ok !'];
                colLinOp='green';
            end
                                      
            % Open Log Windows
            OpenLog(app,errMsgLinOp,colLinOp);

        end

        % Button pushed function: ButtonSelectPipeline
        function ButtonPushed_SelectPipeline(app, event)
            pp=app.DropDownPipeline.Value;
            % Hide
            HideLinOp(app);
            HideDF(app);
            HideRegul(app);
            HideOpti(app); 
            % Re-Initialize
            InitProperties(app);  
            % Get selected pipeline
            enable=true;
            switch pp
                case 'New'                  
                    app.EditFieldPipeline.Value = 'MyPipeline';
                    app.pipeline.name=app.EditFieldPipeline.Value;
                    app.TextAreaDescriptionPipeline.Value = 'Describe your pipeline';
                    app.pipeline.dscpt = app.TextAreaDescriptionPipeline.Value;                                      
                otherwise
                    cont=true;
                    if strcmp(pp,'Browse')
                        [file,path]=uigetfile();
                        if file~=0
                            Pipeline=load([path,file]);
                        else
                            cont=false;
                            enable=false;
                        end
                    else
                        Pipeline=load(['pipelines/',pp]);
                    end
                    if cont
                        app.LinOpCell=Pipeline.LinOpCell;
                        app.DFCell=Pipeline.DFCell;
                        app.RegulCell=Pipeline.RegulCell;
                        app.OptiCell=Pipeline.OptiCell; 
                        app.pipeline=Pipeline.pipeline;
                        app.isPositivity=Pipeline.isPositivity;
                        app.EditFieldPipeline.Value = app.pipeline.name;
                        app.TextAreaDescriptionPipeline.Value = app.pipeline.dscpt;
                    end
            end
            % Show
            ShowLinOp(app);
            ShowDF(app);
            ShowOpti(app); 
            ShowRegul(app);
            if enable
                app.EditFieldPipeline.Editable = 'on';
                app.DescriptionTextAreaLabel.Enable = 'on';
                app.TextAreaDescriptionPipeline.Enable = 'on';
                app.TextAreaDescriptionPipeline.Editable = 'on';
                app.ButtonSaveMainPanel.Enable = 'on';app.ButtonSaveFwdPanel.Enable = 'on';app.ButtonSaveCostPanel.Enable = 'on';app.ButtonSaveOptiPanel.Enable = 'on';
                app.TextAreaForwardModel.Enable = 'on';app.TextAreaCost.Enable = 'on';
                app.ButtonInfoLinOp.Enable = 'on';app.ButtonInfoDF.Enable= 'on';app.ButtonInfoRegul.Enable= 'on';app.ButtonInfoOpti.Enable= 'on';
                app.ButtonSeeDocLinOp.Enable = 'on';app.ButtonSeeDocDF.Enable = 'on';app.ButtonSeeDocRegul.Enable = 'on';app.ButtonSeeDocOpti.Enable = 'on';
                app.DropDownLinOp.Enable = 'on';app.DropDownDF.Enable = 'on';app.DropDownRegul.Enable = 'on';app.DropDownOpti.Enable = 'on';
                app.OperatorLabel.Enable = 'on';app.DatafidelityLabel.Enable = 'on';app.RegularizerLabel.Enable = 'on';app.AlgorithmLabel.Enable = 'on';
                app.ButtonCheckPipelineFwdPanel.Enable = 'on';app.CheckBoxPositivity.Enable = 'on';app.ButtonCheckPipelineCostPanel.Enable = 'on';
                app.ButtonSelectLinOp.Enable = 'on';app.ButtonSelectDF.Enable = 'on';app.ButtonSelectRegul.Enable = 'on';app.ButtonSelectOpti.Enable = 'on';
                app.LabelTextArea.Enable = 'on';app.CostFunctionLabel.Enable = 'on';
                app.ButtonGenerateScript.Enable ='on';app.ButtonParametersRecap.Enable = 'on';app.CheckBoxCostFunctionDisplay.Enable = 'on';
            else
                app.EditFieldPipeline.Editable = 'off';
                app.DescriptionTextAreaLabel.Enable = 'off';
                app.TextAreaDescriptionPipeline.Enable = 'off';
                app.TextAreaDescriptionPipeline.Editable = 'off';
                app.ButtonSaveMainPanel.Enable = 'off';app.ButtonSaveFwdPanel.Enable = 'off';app.ButtonSaveCostPanel.Enable = 'off';app.ButtonSaveOptiPanel.Enable = 'off';
                app.TextAreaForwardModel.Enable = 'off';app.TextAreaCost.Enable = 'off';
                app.ButtonInfoLinOp.Enable = 'off';app.ButtonInfoDF.Enable= 'off';app.ButtonInfoRegul.Enable= 'off';app.ButtonInfoOpti.Enable= 'off';
                app.ButtonSeeDocLinOp.Enable = 'off';app.ButtonSeeDocDF.Enable = 'off';app.ButtonSeeDocRegul.Enable = 'off';app.ButtonSeeDocOpti.Enable = 'off';
                app.DropDownLinOp.Enable = 'off';app.DropDownDF.Enable = 'off';app.DropDownRegul.Enable = 'off';app.DropDownOpti.Enable = 'off';
                app.OperatorLabel.Enable = 'off';app.DatafidelityLabel.Enable = 'off';app.RegularizerLabel.Enable = 'off';app.AlgorithmLabel.Enable = 'off';
                app.ButtonCheckPipelineFwdPanel.Enable = 'off';app.CheckBoxPositivity.Enable = 'off';app.ButtonCheckPipelineCostPanel.Enable = 'off';
                app.ButtonSelectLinOp.Enable = 'off';app.ButtonSelectDF.Enable = 'off';app.ButtonSelectRegul.Enable = 'off';app.ButtonSelectOpti.Enable = 'off';
                app.LabelTextArea.Enable = 'off';app.CostFunctionLabel.Enable = 'off';
                app.ButtonGenerateScript.Enable ='off';app.ButtonParametersRecap.Enable = 'off';app.CheckBoxCostFunctionDisplay.Enable = 'off';
            end
        end

        % Button pushed function: ButtonSaveCostPanel, 
        % ButtonSaveFwdPanel, ButtonSaveMainPanel, ButtonSaveOptiPanel
        function ButtonPushed_Save(app, event)
            % Get the filename and the path to save the pipeline
            [file,path]=uiputfile('.mat','Save pipeline',[app.EditFieldPipeline.Value,'.mat']);
            % Save the properties LinOpCell, RegulCell, DFCell, and pipeline
            if file~=0
                LinOpCell = app.LinOpCell;
                RegulCell = app.RegulCell;
                DFCell = app.DFCell;
                OptiCell = app.OptiCell; 
                isPositivity = app.isPositivity;
                pipeline = app.pipeline;
                save([path,file],'LinOpCell','RegulCell','DFCell','OptiCell','pipeline','isPositivity');
                clear LinOpCell RegulCell DFCell OptiCell pipeline isPositivity
            end
        end

        % Value changed function: TextAreaDescriptionPipeline
        function ValueChanged_DescriptionPipeline(app, event)
            app.pipeline.dscpt = app.TextAreaDescriptionPipeline.Value;
        end

        % Value changed function: EditFieldPipeline
        function ValueChanged_NamePipeline(app, event)
            app.pipeline.name = app.EditFieldPipeline.Value;
        end

        % Button pushed function: ButtonDeleteDF1, 
        % ButtonDeleteLinOp1, ButtonDeleteLinOp2, ButtonDeleteLinOp3, 
        % ButtonDeleteLinOp4, ButtonDeleteLinOp5, ButtonDeleteLinOp6, 
        % ButtonDeleteOpti1, ButtonDeleteRegul1, ButtonDeleteRegul2, 
        % ButtonDeleteRegul3
        function ButtonPushed_Delete(app, event)
            tag=event.Source.Tag(1:end-1);         % Get if LinOp / DF / Regul
            idx=str2double(event.Source.Tag(end)); % Get the index of the object to remove
            switch tag
                case 'LinOp'
                    IdxFirst=1;
                    IdxLast=app.maxLinOp;
                case 'DF'
                    IdxFirst=app.maxLinOp+1;
                    IdxLast=app.maxLinOp+app.maxDF;
                case 'Regul'
                    IdxFirst=app.maxLinOp+app.maxDF+1;
                    IdxLast=app.maxLinOp+app.maxDF+app.maxRegul;
                case 'Opti'
                    IdxFirst=app.maxLinOp+app.maxDF+app.maxRegul+1;
                    IdxLast=app.maxLinOp+app.maxDF+app.maxRegul+app.maxOpti;
            end
            % Hide
            eval(['Hide',tag,'(app);']);
            % Update LinOpCell and the SetParamsFigs
            eval(['tmp=app.',tag,'Cell;']);
            tmp2=app.SetParamsFigs;
            eval(['app.',tag,'Cell={};']);
            app.SetParamsFigs(IdxFirst:IdxLast)=cell(1,IdxLast-IdxFirst+1);
            pos=1;
            for ii=1:length(tmp)
                if ii~=idx
                    eval(['app.',tag,'Cell{pos}=tmp{ii};']);
                    app.SetParamsFigs{pos}=tmp2{ii};
                    pos=pos+1;
                end
            end
            % Show
            eval(['Show',tag,'(app);']);
            % Enable SelectLinOp button if max number of linOps not reached
            if length(app.LinOpCell)<app.maxLinOp
                eval(['app.ButtonSelect',tag,'.Enable = "on";']);
            end
        end

        % Button pushed function: ButtonDownLinOp1, ButtonDownLinOp2, 
        % ButtonDownLinOp3, ButtonDownLinOp4, ButtonDownLinOp5, 
        % ButtonDownLinOp6
        function ButtonPushed_Down(app, event)
            % Get the index of the object to move down
            idx=str2double(event.Source.Tag(end));   
            % Hide Hierarchy LinOps
            HideLinOp(app);
            % Reorganize the LinOpCell and the SetParamsFigs
            tmp=app.LinOpCell{idx+1};
            app.LinOpCell{idx+1}=app.LinOpCell{idx};
            app.LinOpCell{idx}=tmp;
            tmp=app.SetParamsFigs{idx+1};
            app.SetParamsFigs{idx+1}=app.SetParamsFigs{idx};
            app.SetParamsFigs{idx}=tmp;
            % Show Hierarchy LinOps
            ShowLinOp(app);
        end

        % Button pushed function: ButtonUpLinOp1, ButtonUpLinOp2, 
        % ButtonUpLinOp3, ButtonUpLinOp4, ButtonUpLinOp5, 
        % ButtonUpLinOp6
        function ButtonPushed_Up(app, event)
            % Get the index of the object to move up
            idx=str2double(event.Source.Tag(end));  
            % Hide Hierarchy LinOps
            HideLinOp(app);
            % Reorganize the LinOpCell and the SetParamsFigs
            tmp=app.LinOpCell{idx-1};
            app.LinOpCell{idx-1}=app.LinOpCell{idx};
            app.LinOpCell{idx}=tmp;
            tmp=app.SetParamsFigs{idx-1};
            app.SetParamsFigs{idx-1}=app.SetParamsFigs{idx};
            app.SetParamsFigs{idx}=tmp;
            % Show Hierarchy LinOps
            ShowLinOp(app);
        end

        % Button pushed function: ButtonSetParamsDF1, 
        % ButtonSetParamsLinOp1, ButtonSetParamsLinOp2, 
        % ButtonSetParamsLinOp3, ButtonSetParamsLinOp4, 
        % ButtonSetParamsLinOp5, ButtonSetParamsLinOp6, 
        % ButtonSetParamsOpti1, ButtonSetParamsRegul1, 
        % ButtonSetParamsRegul2, ButtonSetParamsRegul3
        function ButtonPushed_SetParams(app, event)
            tag=event.Source.Tag(1:end-1);             % Get if LinOp / DF / Regul
            eval(['app.nbSetParamsFigs.',tag,'=app.nbSetParamsFigs.',tag,'+1;']);  % Increase the counter of opened Set Params Figs
            idx=str2double(event.Source.Tag(end));     % Get the index of the object whose parameters have to be set
            switch tag
                case 'LinOp'
                    cc=app.LinOpCell{idx}.parameters;
                    idx2=idx;
                case 'DF'
                    cc=app.DFCell{idx}.parameters;
                    idx2=app.maxLinOp+idx;
                case 'Regul'
                    cc=app.RegulCell{idx}.parameters;
                    idx2=app.maxLinOp+app.maxDF+idx;    
                case 'Opti'
                    cc=app.OptiCell{idx}.parameters;
                    idx2=app.maxLinOp+app.maxDF+app.maxRegul+idx;    
            end
            if isempty(app.SetParamsFigs{idx2})
                % Create Figure
                hh=app.incr_height*(length(cc)+1);
                app.SetParamsFigs{idx2}.fig = uifigure;
                app.SetParamsFigs{idx2}.fig.Resize = 'off';
                app.SetParamsFigs{idx2}.fig.Position  = app.GlobalBioImGUIUIFigure.Position  +  [app.GlobalBioImGUIUIFigure.Position(3) app.GlobalBioImGUIUIFigure.Position(4)-hh 0 -app.GlobalBioImGUIUIFigure.Position(4)+hh];
                app.SetParamsFigs{idx2}.fig.Name  = 'Set Parameters';
                set(app.SetParamsFigs{idx2}.fig,'CloseRequestFcn',[]);  % Disable using the cross to close the window
                
                % Create Name
                app.SetParamsFigs{idx2}.title = uilabel(app.SetParamsFigs{idx2}.fig);
                app.SetParamsFigs{idx2}.title.FontSize = 16;
                app.SetParamsFigs{idx2}.title.FontWeight = 'bold';
                app.SetParamsFigs{idx2}.title.Position = [19 hh-45 428 38];
                app.SetParamsFigs{idx2}.title.Text = getfield(app,['Name',event.Source.Tag],'Text');
                
                % Create See Doc  button
                app.SetParamsFigs{idx2}.ButtonSeeDoc = uibutton(app.SetParamsFigs{idx2}.fig, 'push');
                app.SetParamsFigs{idx2}.ButtonSeeDoc.Position = [280 hh-35 50 20];
                app.SetParamsFigs{idx2}.ButtonSeeDoc.Text = 'Doc.';                            
                % TODO : FIX ME
                %app.SetParamsFigs{idx2}.ButtonSeeDoc.ButtonPushedFcn = createCallbackFcn(app,@ButtonPushed_SeeDoc, true);
                
                % Create Apply Changes button
                app.SetParamsFigs{idx2}.ButtonApplyChanges = uibutton(app.SetParamsFigs{idx2}.fig, 'push');
                app.SetParamsFigs{idx2}.ButtonApplyChanges.Position = [350 hh-35 100 20];
                app.SetParamsFigs{idx2}.ButtonApplyChanges.Text = 'Apply Changes';
                app.SetParamsFigs{idx2}.ButtonApplyChanges.ButtonPushedFcn = createCallbackFcn(app,@ButtonPushed_ApplyChanges,true);
                                
                % Display parameters selection
                for ii=1:length(cc)
                    pos_bott      = hh-(ii+0.5)*app.incr_height;
                    % Info Button
                    app.SetParamsFigs{idx2}.param{ii}.info = uibutton(app.SetParamsFigs{idx2}.fig, 'push');
                    app.SetParamsFigs{idx2}.param{ii}.info.Icon = 'infoIcon.png';
                    app.SetParamsFigs{idx2}.param{ii}.info.Tooltip = {cc{ii}.info};
                    app.SetParamsFigs{idx2}.param{ii}.info.Position = [30 pos_bott 20 app.def_height];
                    app.SetParamsFigs{idx2}.param{ii}.info.Text = '';
                    typ=cc{ii}.type;
                    % Label
                    app.SetParamsFigs{idx2}.param{ii}.label = uilabel(app.SetParamsFigs{idx2}.fig);
                    app.SetParamsFigs{idx2}.param{ii}.label.HorizontalAlignment = 'left';
                    app.SetParamsFigs{idx2}.param{ii}.label.Position = [60 pos_bott 80 app.def_height];
                    app.SetParamsFigs{idx2}.param{ii}.label.Text = [cc{ii}.name,' : '];
                    switch  typ
                        case 'file'
                            app.SetParamsFigs{idx2}.param{ii}.TextArea = uitextarea(app.SetParamsFigs{idx2}.fig);
                            app.SetParamsFigs{idx2}.param{ii}.TextArea.Position = [150 pos_bott 220 app.def_height];
                            tt=cc{ii}.val;
                            if isempty(tt), tt='Select a file (.png,.jpg,.tif,.mat)'; end
                            app.SetParamsFigs{idx2}.param{ii}.TextArea.Value=tt;
                            app.SetParamsFigs{idx2}.param{ii}.butBrowse = uibutton(app.SetParamsFigs{idx2}.fig, 'push');
                            app.SetParamsFigs{idx2}.param{ii}.butBrowse.Text = 'Browse';
                            app.SetParamsFigs{idx2}.param{ii}.butBrowse.Position = [380 pos_bott 60 app.def_height];
                            app.SetParamsFigs{idx2}.param{ii}.butBrowse.ButtonPushedFcn = createCallbackFcn(app, @(app,event) ButtonPushed_SetParamsFigBrowse(app,event,ii), true);
                            app.SetParamsFigs{idx2}.param{ii}.butBrowse.Tag = event.Source.Tag;
                        case 'vecInt'
                            app.SetParamsFigs{idx2}.param{ii}.openBracket = uilabel(app.SetParamsFigs{idx2}.fig);
                            app.SetParamsFigs{idx2}.param{ii}.openBracket.Position = [140 pos_bott-5 10 app.def_height*1.8];
                            app.SetParamsFigs{idx2}.param{ii}.openBracket.Text = '[';
                            app.SetParamsFigs{idx2}.param{ii}.openBracket.FontSize = 30;
                            app.SetParamsFigs{idx2}.param{ii}.closeBracket = uilabel(app.SetParamsFigs{idx2}.fig);
                            app.SetParamsFigs{idx2}.param{ii}.closeBracket.Position = [232 pos_bott-5 10 app.def_height*1.8];
                            app.SetParamsFigs{idx2}.param{ii}.closeBracket.Text = ']';
                            app.SetParamsFigs{idx2}.param{ii}.closeBracket.FontSize = 30;
                            app.SetParamsFigs{idx2}.param{ii}.EditField = uieditfield(app.SetParamsFigs{idx2}.fig,'text');
                            app.SetParamsFigs{idx2}.param{ii}.EditField.Position = [150 pos_bott 80 app.def_height];
                            app.SetParamsFigs{idx2}.param{ii}.EditField.Value=  cc{ii}.val;
                            app.SetParamsFigs{idx2}.param{ii}.EditField.ValueChangedFcn = createCallbackFcn(app, @(app,event) ValueChanged_SetParamsFigEdit(app,event,ii), true);
                            app.SetParamsFigs{idx2}.param{ii}.EditField.Tag = event.Source.Tag;
                        case 'double'
                            app.SetParamsFigs{idx2}.param{ii}.EditField = uieditfield(app.SetParamsFigs{idx2}.fig,'numeric');
                            app.SetParamsFigs{idx2}.param{ii}.EditField.Position = [150 pos_bott 80 app.def_height];
                            app.SetParamsFigs{idx2}.param{ii}.EditField.Value=  str2num(cc{ii}.val);
                            app.SetParamsFigs{idx2}.param{ii}.EditField.ValueChangedFcn = createCallbackFcn(app, @(app,event) ValueChanged_SetParamsFigEdit(app,event,ii), true);
                            app.SetParamsFigs{idx2}.param{ii}.EditField.Tag = event.Source.Tag;
                        case 'dropDown'
                            app.SetParamsFigs{idx2}.param{ii}.dropDown = uidropdown(app.SetParamsFigs{idx2}.fig);
                            app.SetParamsFigs{idx2}.param{ii}.dropDown.Position = [150 pos_bott 80 app.def_height];
                            app.SetParamsFigs{idx2}.param{ii}.dropDown.Items = cc{ii}.items;
                            app.SetParamsFigs{idx2}.param{ii}.dropDown.Value = cc{ii}.val;
                            app.SetParamsFigs{idx2}.param{ii}.dropDown.ValueChangedFcn = createCallbackFcn(app, @(app,event) ValueChanged_SetParamsFigDropDown(app,event,ii), true);
                            app.SetParamsFigs{idx2}.param{ii}.dropDown.Tag = event.Source.Tag;
                        case 'boolean'
                            app.SetParamsFigs{idx2}.param{ii}.boolean = uiswitch(app.SetParamsFigs{idx2}.fig, 'slider');
                            app.SetParamsFigs{idx2}.param{ii}.boolean.Position = [160 pos_bott 35 15];
                            app.SetParamsFigs{idx2}.param{ii}.boolean.Items = {'0','1'};
                            app.SetParamsFigs{idx2}.param{ii}.boolean.Value = cc{ii}.val;
                            app.SetParamsFigs{idx2}.param{ii}.boolean.ValueChangedFcn = createCallbackFcn(app, @(app,event) ValueChanged_SetParamsFigBoolean(app,event,ii), true);
                            app.SetParamsFigs{idx2}.param{ii}.boolean.Tag = event.Source.Tag;
                    end         
                end
            else
                % Show figure (already created)
                app.SetParamsFigs{idx2}.fig.Visible='on';pause(0.1);
            end
            
            % Set Tags
            app.SetParamsFigs{idx2}.ButtonApplyChanges.Tag=event.Source.Tag;
            app.SetParamsFigs{idx2}.ButtonSeeDoc.Tag = event.Source.Tag;
            for ii=1:length(cc)
                typ=cc{ii}.type;
                switch  typ
                    case 'file'
                        app.SetParamsFigs{idx2}.param{ii}.butBrowse.Tag = event.Source.Tag;
                    case 'vecInt'
                        app.SetParamsFigs{idx2}.param{ii}.EditField.Tag = event.Source.Tag;
                        app.SetParamsFigs{idx2}.param{ii}.EditField.Editable='on';
                end
            end
            
            % Disable buttons Up/Down/Delete
            eval(['app.ButtonSelect',tag,'.Enable="off";']);
            app = setfield(app,['ButtonSetParams',event.Source.Tag],'Enable','off');
            eval(['nobj=length(app.',tag,'Cell);']);
            for ii=1:nobj
                if strcmp(tag,'LinOp')
                    app = setfield(app,['ButtonUp',tag,num2str(ii)],'Enable','off');
                    app = setfield(app,['ButtonDown',tag,num2str(ii)],'Enable','off');
                end
                app = setfield(app,['ButtonDelete',tag,num2str(ii)],'Enable','off');
            end
        end

        % Button pushed function: ButtonSelectDF, ButtonSelectLinOp, 
        % ButtonSelectOpti, ButtonSelectRegul
        function ButtonPushed_Select(app, event)
            % Get Tag
            tag=event.Source.Tag;    
            
            % Get length cell
            eval(['idx=length(app.',tag,'Cell)+1;']);    
            
            % Add name info
            idshiftparam=0;
            switch tag
                case 'LinOp'
                    prefix='LinOp';
                    app.LinOpCell{idx}.name=app.DropDownLinOp.Value;                    
                case 'DF'
                    prefix='Cost';
                    for i=1:length(app.dfListGUI)
                        if strcmp(app.DropDownDF.Value, app.dfListGUI{i})
                            app.DFCell{idx}.name = app.dfListCode{i};
                        end
                    end
                    % Specific to DF
                    app.ButtonSelectRegul.Enable = 'on';
                    app.ButtonSeeDocRegul.Enable = 'on';
                    app.DropDownRegul.Enable = 'on';
                    app.CheckBoxPositivity.Enable = 'on';
                case 'Regul'
                    prefix='Regul';
                    app.RegulCell{idx}.name=app.DropDownRegul.Value;
                    app.RegulCell{idx}.parameters{1}.name = 'lambda';
                    app.RegulCell{idx}.parameters{1}.type = 'double';
                    app.RegulCell{idx}.parameters{1}.val = '1e-1';
                    app.RegulCell{idx}.parameters{1}.info = 'Regularization parameter';
                    idshiftparam=1;
                case 'Opti'
                    prefix='Opti';
                    app.OptiCell{idx}.name=app.DropDownOpti.Value;
            end       
            
            % Hide 
            eval(['Hide',tag,'(app);']);
            
            % Get parameters from comments
            text=splitlines(fileread([prefix,eval(['app.',tag,'Cell{idx}.name']),'.m']));
            %    Get notation
            splitxt=regexp(text{~cellfun(@isempty, regexp(text, 'GUInotation'))},'-','split'); 
            eval(['app.',tag,'Cell{idx}.notation=splitxt{2};']);  
            %    Get call line
            if strcmp(tag,'Regul')                                                             
                splitxt=regexp(text{~cellfun(@isempty, regexp(text, 'GUIcallOpReg'))},'-','split'); 
                eval(['app.',tag,'Cell{idx}.callOpReg=splitxt{2};']);
                splitxt=regexp(text{~cellfun(@isempty, regexp(text, 'GUIcallCostReg'))},'-','split'); 
                eval(['app.',tag,'Cell{idx}.callCostReg=splitxt{2};']);
            else
                splitxt=regexp(text{~cellfun(@isempty, regexp(text, 'GUIcall'))},'-','split'); 
                eval(['app.',tag,'Cell{idx}.call=splitxt{2};']);
            end        
            %    Get parameters
            ll=text(~cellfun(@isempty, regexp(text, 'GUIparam')));  
            for ii=1:length(ll)
                splitxt=regexp(ll{ii},'-','split');
                eval(['app.',tag,'Cell{idx}.parameters{ii+idshiftparam}.name=splitxt{2};']);                
                if ~isempty(regexp(splitxt{3},'dropDown','once'))
                    eval(['app.',tag,'Cell{idx}.parameters{ii+idshiftparam}.type=''dropDown'';']);
                    tt=regexp(splitxt{3},'/','split');
                    eval(['app.',tag,'Cell{idx}.parameters{ii+idshiftparam}.items=tt(2:end);']);
                else
                    eval(['app.',tag,'Cell{idx}.parameters{ii+idshiftparam}.type=splitxt{3};']);
                end
                if strcmp('[]',splitxt{4}), text=''; else, text=splitxt{4}; end               
                eval(['app.',tag,'Cell{idx}.parameters{ii+idshiftparam}.val=text;']);
                eval(['app.',tag,'Cell{idx}.parameters{ii+idshiftparam}.info=splitxt{5};']);
            end           
            % Show 
            eval(['Show',tag,'(app);']);
   
            % Disable select button if max number reached
            eval(['disa=length(app.',tag,'Cell)>=app.max',tag,';']);
            if disa
                eval(['app.ButtonSelect',tag,'.Enable = "off";']);
            end    
        end

        % Value changed function: CheckBoxPositivity
        function CheckBoxChanged_Positivity(app, event)
            app.isPositivity = app.CheckBoxPositivity.Value;
            % Set positivity
            if app.CheckBoxPositivity.Value
                app.PosText = [' + Pos(x)' ];
            else
                app.PosText = '';
            end
            % Update text for cost
            app.TextAreaCost.Value = ['C(x) = ',app.DFText,app.RegulText, app.PosText];
        end

        % Button pushed function: ButtonGenerateScript
        function ButtonPushed_GenerateScript(app, event)
            % Reset Script
            ResetScript(app);

            % ======================================
            %           UPDATE HEADER  
            % ======================================
            app.script.header{length(app.script.header)+1} = ['% ',app.EditFieldPipeline.Value];
            app.script.header{length(app.script.header)+1} = '%';
            app.script.header{length(app.script.header)+1} = '% Description:';
            app.script.header=[app.script.header,transpose(cellfun(@(x) ['%    ',x],app.TextAreaDescriptionPipeline.Value,'UniformOutput',false))];
            app.script.header{length(app.script.header)+1} = '%';
            app.script.header{length(app.script.header)+1} = ['% Generated on ',date,' using the GUI of the GlobalBioIm library'];
            app.script.header{length(app.script.header)+1} = ['% Link to GlobalBioIm: <https://biomedical-imaging-group.github.io/GlobalBioIm/>'];
            app.script.header{length(app.script.header)+1} = '%----------------------------------------------';
            
            % ======================================
            %           PARAMETER LOADING 
            % ======================================            
            % --- ForwardModel parameters 
            for idx=1:length(app.LinOpCell)       % Loop over LinOps
                Op=['H',num2str(idx)];            % LinOp Notation in the script
                app.script.params{length(app.script.params)+1} = ['% - Operator-',num2str(idx),' : ',app.LinOpCell{idx}.name];  % Comment with Name
                UpdateScript_ParametersSec(app,app.LinOpCell{idx},Op);
            end           
            % --- CostFunction parameters 
            for idx=1:length(app.DFCell)        % Data-fidelity
                app.script.params{length(app.script.params)+1} = ['% - Data-fidelity : ',app.DFCell{idx}.name];  % Comment with Name
                UpdateScript_ParametersSec(app,app.DFCell{idx},'DF');    
            end
            for idx=1:length(app.RegulCell)     % Regularization
                R=['R',num2str(idx)];
                app.script.params{length(app.script.params)+1} = ['% - Regularization-',num2str(idx),' : ',app.RegulCell{idx}.name];  % Comment with Name
                UpdateScript_ParametersSec(app,app.RegulCell{idx},R);
            end            
            % --- Optimization parameters 
            
            % ======================================
            %       INSTANCIATE FORWARD MODEL 
            % ======================================
            % Loop over LinOps
            for idx=1:length(app.LinOpCell)               
                Op=['H',num2str(idx)];                                                                     % LinOp Notation in the script
                app.script.linop{length(app.script.linop)+1} = ['% - Operator-',num2str(idx),' : ',app.LinOpCell{idx}.name];  % Comment with LinOp Name
                app.script.linop{length(app.script.linop)+1} = UpdateScript_SetPrefixToCalls(app,[Op,' = ',app.LinOpCell{idx}.call,';'],app.LinOpCell{idx},Op);  % Instanciation line              
            end
            
            % ======================================
            %       INSTANCIATE COSTS FUNCTION
            % ======================================
            % -- Data-fidelity
            for idx=1:length(app.DFCell)        
                app.script.cost{length(app.script.cost)+1} = ['% - Data-Fidelity : ',app.DFCell{idx}.name];       % Comment with DF Name
                app.script.cost{length(app.script.cost)+1} = UpdateScript_SetPrefixToCalls(app,['DF = ',app.DFCell{idx}.call,';'],app.DFCell{idx},'DF');
            end    
            % -- Regularization
            nbLinOps=length(app.LinOpCell);
            for idx=1:length(app.RegulCell)
                OpReg=['OpReg',num2str(idx)];
                CostReg=['CostReg',num2str(idx)];
                app.script.cost{length(app.script.cost)+1} = ['% - Regularization-',num2str(idx),' : ',app.RegulCell{idx}.name];  % Comment with Regul Name
                app.script.cost{length(app.script.cost)+1} = UpdateScript_SetPrefixToCalls(app,[OpReg,' = ',app.RegulCell{idx}.callOpReg,';'],app.RegulCell{idx},OpReg);  % Instanciation line OpReg               
                app.script.cost{end}=strrep(app.script.cost{end},'InputSize',['H',num2str(nbLinOps),'.sizein']);% handle InputSize argument
                app.script.cost{length(app.script.cost)+1} = UpdateScript_SetPrefixToCalls(app,[CostReg,' = R',num2str(idx),'_lambda * ',app.RegulCell{idx}.callCostReg,';'],app.RegulCell{idx},CostReg);  % Instanciation line OpReg 
                app.script.cost{end}=strrep(app.script.cost{end},'InputSize','OpReg.sizeout'); % handle InputSize argument
            end
            
            
            % ======================================
            %           INSTANCIATE OPTIS 
            % ======================================
            
            % ======================================
            %           WRITE IN FILE 
            % ======================================           
            [file,path]=uiputfile('.m','Save Script',['GlobalBioIm_',app.EditFieldPipeline.Value,'_Script.m']); % Get the filename and the path to save the pipeline
            if file~=0
                fileID = fopen([path,file],'w');
                fprintf(fileID,'%s\n',app.script.header{:});fprintf(fileID,'\n');
                fprintf(fileID,'%s\n',app.script.params{:});fprintf(fileID,'\n');
                fprintf(fileID,'%s\n',app.script.linop{:});fprintf(fileID,'\n');
                fprintf(fileID,'%s\n',app.script.cost{:});fprintf(fileID,'\n');
                fprintf(fileID,'%s\n',app.script.algo{:});fprintf(fileID,'\n');
                fclose(fileID);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GlobalBioImGUIUIFigure = uifigure;
            app.GlobalBioImGUIUIFigure.Name = 'GlobalBioIm - GUI';
            app.TabGroup = uitabgroup(app.GlobalBioImGUIUIFigure);
            app.Main = uitab(app.TabGroup);
            app.Main.Title = 'Main';
            app.LabelGlobalBioIm = uilabel(app.Main);
            app.LabelGlobalBioIm.Text = 'GlobalBioIm';
            app.ButtonSaveMainPanel = uibutton(app.Main, 'push');
            app.ButtonSaveMainPanel.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Save, true);
            app.ButtonSaveMainPanel.Enable = 'off';
            app.ButtonSaveMainPanel.Text = 'Save';
            app.ButtonSelectPipeline = uibutton(app.Main, 'push');
            app.ButtonSelectPipeline.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SelectPipeline, true);
            app.ButtonSelectPipeline.Text = 'Select';
            app.DropDownPipeline = uidropdown(app.Main);
            app.PipelineEditFieldLabel = uilabel(app.Main);
            app.PipelineEditFieldLabel.Text = 'Pipeline';
            app.EditFieldPipeline = uieditfield(app.Main, 'text');
            app.EditFieldPipeline.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_NamePipeline, true);
            app.EditFieldPipeline.Editable = 'off';
            app.EditFieldPipeline.Value = 'Select a pipeline';
            app.DescriptionTextAreaLabel = uilabel(app.Main);
            app.DescriptionTextAreaLabel.Enable = 'off';
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.TextAreaDescriptionPipeline = uitextarea(app.Main);
            app.TextAreaDescriptionPipeline.ValueChangedFcn = createCallbackFcn(app, @ValueChanged_DescriptionPipeline, true);
            app.TextAreaDescriptionPipeline.Enable = 'off';
            app.LabelCopyright = uilabel(app.Main);
            app.LabelCopyright.Text = 'Copyright (C) 2019 - The GlobalBioIm Team';
            app.ForwardModel = uitab(app.TabGroup);
            app.ForwardModel.Title = 'Forward Model';
            app.TextAreaForwardModel = uitextarea(app.ForwardModel);
            app.TextAreaForwardModel.Editable = 'off';
            app.TextAreaForwardModel.Enable = 'off';
            app.LabelTextArea = uilabel(app.ForwardModel);
            app.LabelTextArea.Enable = 'off';
            app.LabelTextArea.Text = 'Forward Model';
            app.ButtonSelectLinOp = uibutton(app.ForwardModel, 'push');
            app.ButtonSelectLinOp.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Select, true);
            app.ButtonSelectLinOp.Enable = 'off';
            app.ButtonSelectLinOp.Text = 'Select';
            app.ButtonSeeDocLinOp = uibutton(app.ForwardModel, 'push');
            app.ButtonSeeDocLinOp.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SeeDoc, true);
            app.ButtonSeeDocLinOp.Enable = 'off';
            app.ButtonSeeDocLinOp.Text = 'Doc.';
            app.OperatorLabel = uilabel(app.ForwardModel);
            app.OperatorLabel.Enable = 'off';
            app.OperatorLabel.Text = 'Operator';
            app.DropDownLinOp = uidropdown(app.ForwardModel);
            app.DropDownLinOp.Items = {'Test', 'Option 5', 'Test2', 'Option 3', 'Option 4'};
            app.DropDownLinOp.Enable = 'off';
            app.DropDownLinOp.Value = 'Test';
            app.PanelHierarchyLinOp = uipanel(app.ForwardModel);
            app.PanelHierarchyLinOp.Title = 'Hierarchy of Selected Operators';
            app.NotationLinOp1 = uilabel(app.PanelHierarchyLinOp);
            app.NotationLinOp1.Text = 'H1 :';
            app.NameLinOp1 = uilabel(app.PanelHierarchyLinOp);
            app.NameLinOp1.Text = 'LinOp1';
            app.ButtonSetParamsLinOp1 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonSetParamsLinOp1.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SetParams, true);
            app.ButtonSetParamsLinOp1.Text = 'Set Parameters';
            app.ButtonUpLinOp1 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonUpLinOp1.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Up, true);
            app.ButtonUpLinOp1.Icon = 'upArrow.png';
            app.ButtonUpLinOp1.Text = '';
            app.ButtonDownLinOp1 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonDownLinOp1.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Down, true);
            app.ButtonDownLinOp1.Icon = 'downArrow.png';
            app.ButtonDownLinOp1.Text = '';
            app.ButtonDeleteLinOp1 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonDeleteLinOp1.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Delete, true);
            app.ButtonDeleteLinOp1.Icon = 'trashIcon.png';
            app.ButtonDeleteLinOp1.Text = '';
            app.NotationLinOp2 = uilabel(app.PanelHierarchyLinOp);
            app.NotationLinOp2.Text = 'H2 :';
            app.NameLinOp2 = uilabel(app.PanelHierarchyLinOp);
            app.NameLinOp2.Text = 'LinOp2';
            app.ButtonSetParamsLinOp2 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonSetParamsLinOp2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SetParams, true);
            app.ButtonSetParamsLinOp2.Text = 'Set Parameters';
            app.ButtonUpLinOp2 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonUpLinOp2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Up, true);
            app.ButtonUpLinOp2.Icon = 'upArrow.png';
            app.ButtonUpLinOp2.Text = '';
            app.ButtonDownLinOp2 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonDownLinOp2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Down, true);
            app.ButtonDownLinOp2.Icon = 'downArrow.png';
            app.ButtonDownLinOp2.Text = '';
            app.ButtonDeleteLinOp2 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonDeleteLinOp2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Delete, true);
            app.ButtonDeleteLinOp2.Icon = 'trashIcon.png';
            app.ButtonDeleteLinOp2.Text = '';
            app.NotationLinOp3 = uilabel(app.PanelHierarchyLinOp);
            app.NotationLinOp3.Text = 'H3 :';
            app.NameLinOp3 = uilabel(app.PanelHierarchyLinOp);
            app.NameLinOp3.Text = 'LinOp3';
            app.ButtonSetParamsLinOp3 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonSetParamsLinOp3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SetParams, true);
            app.ButtonSetParamsLinOp3.Text = 'Set Parameters';
            app.ButtonUpLinOp3 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonUpLinOp3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Up, true);
            app.ButtonUpLinOp3.Icon = 'upArrow.png';
            app.ButtonUpLinOp3.Text = '';
            app.ButtonDownLinOp3 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonDownLinOp3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Down, true);
            app.ButtonDownLinOp3.Icon = 'downArrow.png';
            app.ButtonDownLinOp3.Text = '';
            app.ButtonDeleteLinOp3 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonDeleteLinOp3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Delete, true);
            app.ButtonDeleteLinOp3.Icon = 'trashIcon.png';
            app.ButtonDeleteLinOp3.Text = '';
            app.NotationLinOp4 = uilabel(app.PanelHierarchyLinOp);
            app.NotationLinOp4.Text = 'H4 :';
            app.NameLinOp4 = uilabel(app.PanelHierarchyLinOp);
            app.NameLinOp4.Text = 'LinOp4';
            app.ButtonSetParamsLinOp4 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonSetParamsLinOp4.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SetParams, true);
            app.ButtonSetParamsLinOp4.Text = 'Set Parameters';
            app.ButtonUpLinOp4 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonUpLinOp4.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Up, true);
            app.ButtonUpLinOp4.Icon = 'upArrow.png';
            app.ButtonUpLinOp4.Text = '';
            app.ButtonDownLinOp4 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonDownLinOp4.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Down, true);
            app.ButtonDownLinOp4.Icon = 'downArrow.png';
            app.ButtonDownLinOp4.Text = '';
            app.ButtonDeleteLinOp4 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonDeleteLinOp4.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Delete, true);
            app.ButtonDeleteLinOp4.Icon = 'trashIcon.png';
            app.ButtonDeleteLinOp4.Text = '';
            app.NotationLinOp5 = uilabel(app.PanelHierarchyLinOp);
            app.NotationLinOp5.Text = 'H5 :';
            app.NameLinOp5 = uilabel(app.PanelHierarchyLinOp);
            app.NameLinOp5.Text = 'LinOp5';
            app.ButtonSetParamsLinOp5 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonSetParamsLinOp5.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SetParams, true);
            app.ButtonSetParamsLinOp5.Text = 'Set Parameters';
            app.ButtonUpLinOp5 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonUpLinOp5.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Up, true);
            app.ButtonUpLinOp5.Icon = 'upArrow.png';
            app.ButtonUpLinOp5.Text = '';
            app.ButtonDownLinOp5 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonDownLinOp5.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Down, true);
            app.ButtonDownLinOp5.Icon = 'downArrow.png';
            app.ButtonDownLinOp5.Text = '';
            app.ButtonDeleteLinOp5 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonDeleteLinOp5.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Delete, true);
            app.ButtonDeleteLinOp5.Icon = 'trashIcon.png';
            app.ButtonDeleteLinOp5.Text = '';
            app.NotationLinOp6 = uilabel(app.PanelHierarchyLinOp);
            app.NotationLinOp6.Text = 'H6 :';
            app.NameLinOp6 = uilabel(app.PanelHierarchyLinOp);
            app.NameLinOp6.Text = 'LinOp6';
            app.ButtonSetParamsLinOp6 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonSetParamsLinOp6.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SetParams, true);
            app.ButtonSetParamsLinOp6.Text = 'Set Parameters';
            app.ButtonUpLinOp6 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonUpLinOp6.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Up, true);
            app.ButtonUpLinOp6.Icon = 'upArrow.png';
            app.ButtonUpLinOp6.Text = '';
            app.ButtonDownLinOp6 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonDownLinOp6.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Down, true);
            app.ButtonDownLinOp6.Icon = 'downArrow.png';
            app.ButtonDownLinOp6.Text = '';
            app.ButtonDeleteLinOp6 = uibutton(app.PanelHierarchyLinOp, 'push');
            app.ButtonDeleteLinOp6.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Delete, true);
            app.ButtonDeleteLinOp6.Icon = 'trashIcon.png';
            app.ButtonDeleteLinOp6.Text = '';
            app.ButtonInfoLinOp = uibutton(app.ForwardModel, 'push');
            app.ButtonInfoLinOp.Icon = 'infoIcon.png';
            app.ButtonInfoLinOp.Enable = 'off';
            app.ButtonInfoLinOp.Text = '';
            app.ButtonCheckPipelineFwdPanel = uibutton(app.ForwardModel, 'push');
            app.ButtonCheckPipelineFwdPanel.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_CheckPipeline, true);
            app.ButtonCheckPipelineFwdPanel.Enable = 'off';
            app.ButtonCheckPipelineFwdPanel.Text = 'Check Pipeline';
            app.ButtonSaveFwdPanel = uibutton(app.ForwardModel, 'push');
            app.ButtonSaveFwdPanel.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Save, true);
            app.ButtonSaveFwdPanel.Enable = 'off';
            app.ButtonSaveFwdPanel.Text = 'Save';
            app.CostFunction = uitab(app.TabGroup);
            app.CostFunction.Title = 'Cost Function';
            app.TextAreaCost = uitextarea(app.CostFunction);
            app.TextAreaCost.Editable = 'off';
            app.TextAreaCost.Enable = 'off';
            app.CostFunctionLabel = uilabel(app.CostFunction);
            app.CostFunctionLabel.Enable = 'off';
            app.CostFunctionLabel.Text = 'Cost Function';
            app.ButtonSelectDF = uibutton(app.CostFunction, 'push');
            app.ButtonSelectDF.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Select, true);
            app.ButtonSelectDF.Enable = 'off';
            app.ButtonSelectDF.Text = 'Select';
            app.ButtonSeeDocDF = uibutton(app.CostFunction, 'push');
            app.ButtonSeeDocDF.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SeeDoc, true);
            app.ButtonSeeDocDF.Enable = 'off';
            app.ButtonSeeDocDF.Text = 'Doc.';
            app.DatafidelityLabel = uilabel(app.CostFunction);
            app.DatafidelityLabel.Enable = 'off';
            app.DatafidelityLabel.Text = 'Data-fidelity';
            app.DropDownDF = uidropdown(app.CostFunction);
            app.DropDownDF.Items = {'Test', 'Option 5', 'Test2', 'Option 3', 'Option 4'};
            app.DropDownDF.Enable = 'off';
            app.DropDownDF.Value = 'Test';
            app.FMPanel_CostFunction = uipanel(app.CostFunction);
            app.FMPanel_CostFunction.Title = 'Selected Regularization Terms';
            app.NotationRegul1 = uilabel(app.FMPanel_CostFunction);
            app.NotationRegul1.Text = 'R1 :';
            app.NameRegul1 = uilabel(app.FMPanel_CostFunction);
            app.NameRegul1.Text = 'Regul1';
            app.ButtonSetParamsRegul1 = uibutton(app.FMPanel_CostFunction, 'push');
            app.ButtonSetParamsRegul1.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SetParams, true);
            app.ButtonSetParamsRegul1.Text = 'Set Parameters';
            app.ButtonDeleteRegul1 = uibutton(app.FMPanel_CostFunction, 'push');
            app.ButtonDeleteRegul1.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Delete, true);
            app.ButtonDeleteRegul1.Icon = 'trashIcon.png';
            app.ButtonDeleteRegul1.Text = '';
            app.NotationRegul2 = uilabel(app.FMPanel_CostFunction);
            app.NotationRegul2.Text = 'R2 :';
            app.NameRegul2 = uilabel(app.FMPanel_CostFunction);
            app.NameRegul2.Text = 'Regul2';
            app.ButtonSetParamsRegul2 = uibutton(app.FMPanel_CostFunction, 'push');
            app.ButtonSetParamsRegul2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SetParams, true);
            app.ButtonSetParamsRegul2.Text = 'Set Parameters';
            app.ButtonDeleteRegul2 = uibutton(app.FMPanel_CostFunction, 'push');
            app.ButtonDeleteRegul2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Delete, true);
            app.ButtonDeleteRegul2.Icon = 'trashIcon.png';
            app.ButtonDeleteRegul2.Text = '';
            app.NotationRegul3 = uilabel(app.FMPanel_CostFunction);
            app.NotationRegul3.Text = 'R3 :';
            app.NameRegul3 = uilabel(app.FMPanel_CostFunction);
            app.NameRegul3.Text = 'Regul3';
            app.ButtonSetParamsRegul3 = uibutton(app.FMPanel_CostFunction, 'push');
            app.ButtonSetParamsRegul3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SetParams, true);
            app.ButtonSetParamsRegul3.Text = 'Set Parameters';
            app.ButtonDeleteRegul3 = uibutton(app.FMPanel_CostFunction, 'push');
            app.ButtonDeleteRegul3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Delete, true);
            app.ButtonDeleteRegul3.Icon = 'trashIcon.png';
            app.ButtonDeleteRegul3.Text = '';
            app.ButtonSelectRegul = uibutton(app.CostFunction, 'push');
            app.ButtonSelectRegul.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Select, true);
            app.ButtonSelectRegul.Enable = 'off';
            app.ButtonSelectRegul.Text = 'Select';
            app.ButtonSeeDocRegul = uibutton(app.CostFunction, 'push');
            app.ButtonSeeDocRegul.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SeeDoc, true);
            app.ButtonSeeDocRegul.Enable = 'off';
            app.ButtonSeeDocRegul.Text = 'Doc.';
            app.RegularizerLabel = uilabel(app.CostFunction);
            app.RegularizerLabel.Enable = 'off';
            app.RegularizerLabel.Text = 'Regularizer';
            app.DropDownRegul = uidropdown(app.CostFunction);
            app.DropDownRegul.Items = {'Test', 'Option 5', 'Test2', 'Option 3', 'Option 4'};
            app.DropDownRegul.Enable = 'off';
            app.DropDownRegul.Value = 'Test';
            app.CheckBoxPositivity = uicheckbox(app.CostFunction);
            app.CheckBoxPositivity.ValueChangedFcn = createCallbackFcn(app, @CheckBoxChanged_Positivity, true);
            app.CheckBoxPositivity.Enable = 'off';
            app.CheckBoxPositivity.Text = ' Add Positivity Constraint';
            app.FMPanel_CostFunction_2 = uipanel(app.CostFunction);
            app.FMPanel_CostFunction_2.Title = 'Selected Data-Fidelity Term';
            app.NotationDF1 = uilabel(app.FMPanel_CostFunction_2);
            app.NotationDF1.Text = 'DF :';
            app.NameDF1 = uilabel(app.FMPanel_CostFunction_2);
            app.NameDF1.Text = 'DF1';
            app.ButtonSetParamsDF1 = uibutton(app.FMPanel_CostFunction_2, 'push');
            app.ButtonSetParamsDF1.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SetParams, true);
            app.ButtonSetParamsDF1.Text = 'Set Parameters';
            app.ButtonDeleteDF1 = uibutton(app.FMPanel_CostFunction_2, 'push');
            app.ButtonDeleteDF1.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Delete, true);
            app.ButtonDeleteDF1.Icon = 'trashIcon.png';
            app.ButtonDeleteDF1.Text = '';
            app.ButtonInfoDF = uibutton(app.CostFunction, 'push');
            app.ButtonInfoDF.Icon = 'infoIcon.png';
            app.ButtonInfoDF.Enable = 'off';
            app.ButtonInfoDF.Text = '';
            app.ButtonInfoRegul = uibutton(app.CostFunction, 'push');
            app.ButtonInfoRegul.Icon = 'infoIcon.png';
            app.ButtonInfoRegul.Enable = 'off';
            app.ButtonInfoRegul.Text = '';
            app.ButtonSaveCostPanel = uibutton(app.CostFunction, 'push');
            app.ButtonSaveCostPanel.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Save, true);
            app.ButtonSaveCostPanel.Enable = 'off';
            app.ButtonSaveCostPanel.Text = 'Save';
            app.ButtonCheckPipelineCostPanel = uibutton(app.CostFunction, 'push');
            app.ButtonCheckPipelineCostPanel.Enable = 'off';
            app.ButtonCheckPipelineCostPanel.Text = 'Check Pipeline';
            app.Optimization = uitab(app.TabGroup);
            app.Optimization.Title = 'Optimization';
            app.FMPanel_Optimization = uipanel(app.Optimization);
            app.FMPanel_Optimization.Title = 'Selected Algorithm';
            app.NameOpti1 = uilabel(app.FMPanel_Optimization);
            app.NameOpti1.Text = 'ADMM';
            app.ButtonSetParamsOpti1 = uibutton(app.FMPanel_Optimization, 'push');
            app.ButtonSetParamsOpti1.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SetParams, true);
            app.ButtonSetParamsOpti1.Text = 'Set Parameters';
            app.ButtonDeleteOpti1 = uibutton(app.FMPanel_Optimization, 'push');
            app.ButtonDeleteOpti1.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Delete, true);
            app.ButtonDeleteOpti1.Icon = 'trashIcon.png';
            app.ButtonDeleteOpti1.Text = '';
            app.ButtonSelectOpti = uibutton(app.Optimization, 'push');
            app.ButtonSelectOpti.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Select, true);
            app.ButtonSelectOpti.Enable = 'off';
            app.ButtonSelectOpti.Text = 'Select';
            app.ButtonSeeDocOpti = uibutton(app.Optimization, 'push');
            app.ButtonSeeDocOpti.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_SeeDoc, true);
            app.ButtonSeeDocOpti.Enable = 'off';
            app.ButtonSeeDocOpti.Text = 'Doc.';
            app.AlgorithmLabel = uilabel(app.Optimization);
            app.AlgorithmLabel.Enable = 'off';
            app.AlgorithmLabel.Text = 'Algorithm';
            app.DropDownOpti = uidropdown(app.Optimization);
            app.DropDownOpti.Items = {'Test', 'Option 5', 'Test2', 'Option 3', 'Option 4'};
            app.DropDownOpti.Enable = 'off';
            app.DropDownOpti.Value = 'Test';
            app.ButtonInfoOpti = uibutton(app.Optimization, 'push');
            app.ButtonInfoOpti.Icon = 'infoIcon.png';
            app.ButtonInfoOpti.Enable = 'off';
            app.ButtonInfoOpti.Text = '';
            app.ButtonRUN = uibutton(app.Optimization, 'push');
            app.ButtonRUN.Enable = 'off';
            app.ButtonRUN.Text = 'RUN';
            app.ButtonGenerateScript = uibutton(app.Optimization, 'push');
            app.ButtonGenerateScript.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_GenerateScript, true);
            app.ButtonGenerateScript.Enable = 'off';
            app.ButtonGenerateScript.Text = 'Generate Script';
            app.ButtonParametersRecap = uibutton(app.Optimization, 'push');
            app.ButtonParametersRecap.Enable = 'off';
            app.ButtonParametersRecap.Text = 'Recap of All Selected Parameters';
            app.CheckBoxCostFunctionDisplay = uicheckbox(app.Optimization);
            app.CheckBoxCostFunctionDisplay.Enable = 'off';
            app.CheckBoxCostFunctionDisplay.Text = ' Display the cost-function evolution';
            app.ButtonSaveOptiPanel = uibutton(app.Optimization, 'push');
            app.ButtonSaveOptiPanel.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_Save, true);
            app.ButtonSaveOptiPanel.Enable = 'off';
            app.ButtonSaveOptiPanel.Text = 'Save';
            app.ButtonCheckPipelineAlgoPanel = uibutton(app.Optimization, 'push');
            app.ButtonCheckPipelineAlgoPanel.Enable = 'off';
            app.ButtonCheckPipelineAlgoPanel.Text = 'Check Pipeline';
            app.LineOptiPanel = uilabel(app.Optimization);
            app.LineOptiPanel.Text = '__________________________________________________________';
            app.MetaOptionsLabel = uilabel(app.Optimization);
            app.MetaOptionsLabel.Text = 'Meta Options';
            app.CheckBoxCostFunctionDisplay_2 = uicheckbox(app.Optimization);
            app.CheckBoxCostFunctionDisplay_2.Enable = 'off';
            app.CheckBoxCostFunctionDisplay_2.Text = ' Use GPU';
            app.ButtonInfoOpti_2 = uibutton(app.Optimization, 'push');
            app.ButtonInfoOpti_2.Icon = 'infoIcon.png';
            app.ButtonInfoOpti_2.Enable = 'off';
            app.ButtonInfoOpti_2.Text = '';
            app.ButtonInfoOpti_3 = uibutton(app.Optimization, 'push');
            app.ButtonInfoOpti_3.Icon = 'infoIcon.png';
            app.ButtonInfoOpti_3.Enable = 'off';
            app.ButtonInfoOpti_3.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_GlobalBioIm

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GlobalBioImGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GlobalBioImGUIUIFigure)
        end
    end
end